classdef linear_solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SolveButton                     matlab.ui.control.Button
        FunctionsTextAreaLabel          matlab.ui.control.Label
        FunctionsTextArea               matlab.ui.control.TextArea
        LoadFromFileButton              matlab.ui.control.Button
        NumberofEquationsEditFieldLabel  matlab.ui.control.Label
        NumberofEquationsEditField      matlab.ui.control.NumericEditField
        InitialPointsforSeidelSpaceseparatedLabel  matlab.ui.control.Label
        InitialPointsforSeideEditField  matlab.ui.control.EditField
        ErrorsLabel                     matlab.ui.control.Label
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        GaussianeliminationButton       matlab.ui.control.RadioButton
        GaussianJordanButton            matlab.ui.control.RadioButton
        GaussSeidelButton               matlab.ui.control.RadioButton
        LUdecompositionButton           matlab.ui.control.RadioButton
        InputforSeidelPanel             matlab.ui.container.Panel
        PrecisionEditField_3Label       matlab.ui.control.Label
        PrecisionInputSeidelEditField   matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        ResultsofSeidelPanel            matlab.ui.container.Panel
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsResultEditField       matlab.ui.control.NumericEditField
        PrecisionErrorLabel             matlab.ui.control.Label
        PrecisionResultEditField        matlab.ui.control.NumericEditField
        StatusLabel                     matlab.ui.control.Label
        UseAllMethodsButton             matlab.ui.control.Button
        ExecutionTimeEditFieldLabel     matlab.ui.control.Label
        ExecutionTimeEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
%{
        % this is to store the boxes for input
        % since the boxes are generated dynamically, we need a way to reference them
        % thus, each box we generate takes a corresponding location in this 2d array
        EquationEditFields = matlab.ui.control.NumericEditField

        
        numRows % will represent the number of equations
        numCols % the number of columns represents also the number of columns
                % in the matrix
        
        % these values are for customizing the look of GUI
        boxWidth
        boxHeight
        boxVerticalSpacing % space between boxes
        boxHorizontalSpacing
        
        % more than 8 rows looks ugly
        equationLimit
        
        % store the boxes for solutions here
        solutionEditFields = matlab.ui.control.NumericEditField
%}
        
        totalNumberOfEquations
        totalNumberOfUniqueVariables
        errorFlag
        symbolicVariables
        variableMap
        
        end
    
    methods (Access = public)
        
        % All functions take `app` as first arguments to access 
        % the global parameters of the application
    
        % This funciton gets the variables in the systems [e.g. (x, y, z)]
        function ret = checkFunctions(app)
            
            newMap = containers.Map;
            
            numberOfLines = length(app.FunctionsTextArea.Value);
            app.symbolicVariables = [];
            
            % check if number of lines is inconsistant with number of equations
            if (numberOfLines ~= app.totalNumberOfEquations)
                app.errorFlag = 1;
                app.ErrorsLabel.Text = "Error in input:" ...
                    + newline + "Number of lines in input field inconsistant with" ...
                    + newline + "number of equations specified";
            else
            % read the text in the box and count the number of unique variables 
                for i=1:numberOfLines
                    
                    equation_text = app.FunctionsTextArea.Value{i};
                    
                    localSymbolicVariables = symvar(str2sym(equation_text));
                    for var=localSymbolicVariables
                        app.symbolicVariables = [app.symbolicVariables localSymbolicVariables];
                    end
                    
                end
                
                app.symbolicVariables = unique(app.symbolicVariables);
                b = arrayfun(@char, app.symbolicVariables);
                
                for i=1:length(b)
                    newMap(b(i)) = i; 
                end
                
                % if number of variables is more than number of equations, raise error
                if (length(app.symbolicVariables) > app.totalNumberOfEquations)
                   app.ErrorsLabel.Text = "Error: Unsolvable system!" + newline + "variables more than equations!"; 
                   app.errorFlag = 1;
                end
                
                
                app.variableMap = newMap;
            end
            
            ret = 0;  
        end

        % takes the content of functionEditField and creates a matrix
        function [matrix, xs] = fill_matrix(app)
            
            % initialize a matrix representing the equations
            matrix = zeros(app.totalNumberOfEquations, app.totalNumberOfEquations + 1);
            
            for i=1:app.totalNumberOfEquations
                
                sign = 1;
                
                % get one line from the box
                current_equation_text = app.FunctionsTextArea.Value{i};
                
                % take said line and turn it into a cell array
                % where every element was space delimited in box
                equation_array = textscan(current_equation_text, "%s");
                
                % now iterate over each of those cells and 
                for j=1:length(equation_array{1})
                    
                    cell = equation_array{1}{j};
                    
                    %if it is a number, we reached the end of the equation
                    if (isnan(str2double(cell))) %check if the cell contains a numbr or variable
                        if (cell == '-')
                            sign = -1;
                        elseif ( cell == '+')
                            sign = 1;
                        else
                            var = symvar(cell);
                            matrix(i, app.variableMap(var{1})) = sign * coeffs(str2sym(cell));
                        end
                    else 
                        %else if we have number without variable
                        matrix(i, app.totalNumberOfEquations+1) = sign * str2double(cell);
                    end
                end
            end
            
            % get the initial points in case of gauss-seidel
            initPointsCellArray = textscan(app.InitialPointsforSeideEditField.Value, "%d");
            xs = double(initPointsCellArray{1});
            
            if (app.GaussSeidelButton.Value)
                
                % read the numbers in the box
                if (~isempty(app.InitialPointsforSeideEditField.Value))
       
                    % if the init points are not equal to the number of equations
                    % then we have a problem
                    if (length(xs) ~= app.totalNumberOfEquations)
                       app.ErrorsLabel.Text = "Error:" + newline + "total Initial Points must be equal to number of equations"; 
                       app.errorFlag = 1;
                    end
                else
                   app.ErrorsLabel.Text = "Error:" + newline + "No initial points provided!"; 
                   app.errorFlag = 1;
                end
            end
        end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Gaussian Elimination
        function [matrix, ratios] = gaussian_elimination(app, matrix,no_of_eqs)
            matrix
            ratios= zeros(1,(no_of_eqs*no_of_eqs - no_of_eqs)/2);
            ratios_tracker = 1;
            for level = 1:no_of_eqs-1
                for i = level+1 : no_of_eqs
                    a = matrix(i,level)/matrix(level,level) ;
                    ratios(ratios_tracker) = a;
                    ratios_tracker = ratios_tracker  +1;
                    matrix(i,level) = 0;
                    for j = level+1 : no_of_eqs+1
                        matrix(i,j) = matrix(i,j) - ( matrix(level,j) * a) ;
                    end
                end
                matrix
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Gaussian Jordan
        function matrix = gaussian_jordan(app, matrix, no_of_eqs)
            matrix = gaussian_elimination(app, matrix,no_of_eqs);
            matrix = inner_gaussian_jordan(app, matrix,no_of_eqs);
            for i = 2:no_of_eqs
                j = 1;
                while (j < i)
                    a = matrix(j,i);
                    matrix(j,i) = 0 ;
                    for k = i+1:no_of_eqs+1
                        matrix(j,k) = round(matrix(j,k) - (  matrix(i,k) * a), 2);
                    end
                    j = j  + 1;
                end
            end
        end

        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % LU decomposition
        function [L_matrix, U_matrix] = lu_decomposition(app, matrix, no_of_eqs)
            [U_matrix, ratios] = gaussian_elimination(app, matrix, no_of_eqs);
            L_matrix = gaussian_jordan(app, matrix, no_of_eqs);
            tracker = 1;
            for j = 1:no_of_eqs-1
                for i = j+1:no_of_eqs
                    L_matrix(i,j) = ratios(tracker);
                    tracker =tracker +1;
                end
            end
        end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Gauss Seidel
        function [results] = gauss_seidel(app, matrix, no_of_eqs,xs)
            matrix
            % save is 
            ea = 100;
            es = app.PrecisionInputSeidelEditField.Value;
            current_iteration = 1;
            max_iterations = app.MaxIterationsEditField.Value;
                            % number of rows    % number of columns%
            results = zeros(max_iterations, (1 + length(keys(app.variableMap)) + 1));
            
            if (length(xs) ~= no_of_eqs)
                app.errorFlag = 1;
                app.ErrorLabel.Text = "Error: Not Bad initial points input";
            end
            while ( current_iteration <= max_iterations && ea > es)
                
                errors = xs;
                
                for i = 1:no_of_eqs
                    
                    a = matrix(i,i);
                    sum =  matrix(i,no_of_eqs+1);
                    
                    for j = 1:no_of_eqs
                        if (i ~= j)
                            sum = sum + (matrix(i,j) * xs(j))*-1;
                        end
                    end
                    
                    xs(i) = sum/a;
                    errors(i) = abs(((xs(i) -  errors(i))/ xs(i)));
                    
                end
                xs
                ea = max(errors);
                transposed = (xs.')*-1;
                results(current_iteration, :) = [current_iteration transposed ea];
                
                % check for problamitic results (e.g. nan or inf)
                for num=results(current_iteration, :)
                    if (isnan(num) || isinf(num))
                       app.errorFlag = 1; 
                    end
                end
                
                checkForBadResult(app, results(current_iteration, :));
                
                if (app.errorFlag == 1)
                   app.ErrorsLabel.Text = "Error with input functions for for Gauss-Seidel" ...
                              + newline + "Please check that every variable is present" ...
                              + newline + "in at least 2 functions";
                    break;
                end
                
                current_iteration = current_iteration + 1;
            end
            
            header = ["Iteration" keys(app.variableMap) "Precision Error"];
            
            % if there were no bad results when function finished
            if (app.errorFlag ~= 1)
                
                % find first row of all zeros
                first_row_of_zeros = 0;
                for i=1:length(results)
                    if (~(results(i,:)))
                        first_row_of_zeros = i;
                        break;
                    end
                end
                
                % resutls of above
                results = results(1:(first_row_of_zeros - 1),:);
                plot_for_iterative(app, results);
                results = [header; results];
                app.IterationsResultEditField.Value = double(current_iteration);
                app.PrecisionResultEditField.Value = double(ea);
            end
        end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % A helper function
        function matrix = inner_gaussian_jordan(app, matrix,no_of_eqs)
            for i = 1 : no_of_eqs
                deno = matrix(i,i);
                for j = i : (no_of_eqs+1)
                    matrix(i,j)= round(matrix(i,j)/ deno, 2);
                end
            end
        end

    
%%    
        % return the solution of each variable
        function vars = getOutputGaussianelimination(app, matrix, no_of_eqs)
            vars = ones(1,no_of_eqs);
            for i = no_of_eqs:-1: 1
                matrix(i,i) = matrix(i,i) * -1;
                matrix(i,1:no_of_eqs) =  matrix(i,1:no_of_eqs).*vars(1:no_of_eqs);
                x = sum(matrix(i,i+1:no_of_eqs+1));
                vars(i) = (x / matrix(i,i)) ;
            end
            
            if (checkForBadResult(app, vars))
                app.errorFlag = 1;
                app.ErrorsLabel.Text = "Error Solving system in Gaussian elimination" ...
                  + newline + "Check that every variable is present in at least" ...
                  + newline + "2 functions or some other inconsistency";
            end
        end
            
        function result = getOutputGaussianJordan(app, matrix, no_of_eqs)
            result = ones(1,no_of_eqs);
            for i = 1:no_of_eqs
                result(i) = matrix(i,no_of_eqs+1)*-1;
            end
            if (checkForBadResult(app, result))
                app.errorFlag = 1;
                app.ErrorsLabel.Text = "Error Solving system in Gaussian Jordan" ...
                  + newline + "Check that every variable is present in at least" ...
                  + newline + "2 functions or some other inconsistency";
            end
        end
            
        function result = getOutputLUdecomposition(app, matrix, no_of_eqs)
            result = ones(1,no_of_eqs);
            for i=1:no_of_eqs
                result(i) = matrix(i,no_of_eqs+1);
            end
            result = result * -1;
            if (checkForBadResult(app, result))
                app.errorFlag = 1;
                app.ErrorsLabel.Text = "Error Solving system in LU-decomposition" ...
                  + newline + "Check that every variable is present in at least" ...
                  + newline + "2 functions or some other inconsistency";
            end        
        end
        
    
        function plot_for_iterative(app, results)
            [m,n] = size(results);
            
            number_of_variables = n - 2;
            iterations = results(:, 1);
            
            for i =1:number_of_variables
                variable_column = results(:, (1+i));
                plot(iterations, variable_column);
                hold on;
            end
            legend(keys(app.variableMap));
            hold off;
        end
    
        function badResult = checkForBadResult(app, results)
            % check for problamitic results (e.g. nan or inf)
            badResult = 0;
            for num=results
                if (isnan(num) || isinf(num))
                   app.errorFlag = 1;
                   badResult = 1;
                end
            end
        end
    
    
        function printHeader(app, file, header)
            % the header contains names of variables
            % the keys are returned as cells, 
            % so {1} is needed to get the char
            for i=header
                fprintf(file, "%-12s ", i);
            end
            fprintf(file, "\n");   
        end
    
        function printLine(app, file, results)       
            % print the results
            for j=results
                fprintf(file, "%-12f ", j);
            end
            fprintf(file, "\n");
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.errorFlag = 0;
            
            % At first I tried to make it so user can enter numbers into text boxes 
            % but this proved too limiting so it's now dropped
%             app.boxWidth = 60;
%             app.boxHeight = 30;
%             app.boxHorizontalSpacing = 75;
%             app.boxVerticalSpacing = 45;
%             app.equationLimit = 8;
%             fig = app.UIFigure;
%             
%             % for each of 2 rows, create 3 columns
%             for i=1:2
%                 for j=1:3
%                     edt = uieditfield(fig, "numeric",...
%                         'Position',[(app.boxHorizontalSpacing*j)...
%                         (app.UIFigure.Position(4) - app.boxVerticalSpacing*i - 20)...
%                         app.boxWidth app.boxHeight]);
%                     edt.Value = str2double(sprintf("%d%d", i, j));
%                     app.EquationEditFields(i,j) = edt;
%                 end
%                 
%                 % the current number of rows and columns
%                 app.numRows = 2;
%                 app.numCols = 3;
%             end
        end

        % Callback function
        function AddvariableEquationButtonPushed(app, event)
            
%             % arbitrary number of columns: 9
%             if (app.numRows < app.equationLimit)
%                 app.numRows = app.numRows + 1;
%                 app.numCols = app.numCols + 1;
%                 
%                 fig = app.UIFigure;
%                 
%                 % add one column for each existing row
%                 for i=1:app.numRows - 1
%                     edt = uieditfield(fig, "numeric",...
%                         'Position',[(app.boxHorizontalSpacing*app.numCols)...
%                         (app.UIFigure.Position(4) - app.boxVerticalSpacing*i - 20)...
%                         app.boxWidth app.boxHeight]);
%                     app.EquationEditFields(i, app.numCols) = edt;
%                 end
%                 
%                 % add one new row
%                 for i=1:app.numCols
%                     edt = uieditfield(fig, "numeric",...
%                         'Position',[(app.boxHorizontalSpacing*i)...
%                         (app.UIFigure.Position(4) - app.boxVerticalSpacing*app.numRows - 20)...
%                         app.boxWidth app.boxHeight]);
%                     app.EquationEditFields(app.numRows, i) = edt;
%                 end
%             end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            % reset the error flag every time you attempt to solve
            app.errorFlag = 0;
            app.ErrorsLabel.Text = "";
            
            app.totalNumberOfEquations = double(app.NumberofEquationsEditField.Value);
            
            tic;
            [~] = checkFunctions(app);
            
            if (app.errorFlag == 0)
                [matrix, xs] = fill_matrix(app);
            end

            
            if (app.errorFlag == 0)
                if (app.GaussianeliminationButton.Value)
                    output_GE = gaussian_elimination(app, matrix,app.totalNumberOfEquations)
                    results  = getOutputGaussianelimination(app, output_GE, app.totalNumberOfEquations);
                    
                elseif (app.LUdecompositionButton.Value)
                    [output_L, ~] = lu_decomposition(app, matrix, app.totalNumberOfEquations);
                    results  = getOutputLUdecomposition(app, output_L, app.totalNumberOfEquations);
                    
                elseif (app.GaussianJordanButton.Value)
                    output_GJ = gaussian_jordan(app, matrix, app.totalNumberOfEquations)
                    results  = getOutputGaussianJordan(app, output_GJ, app.totalNumberOfEquations);
                    
                elseif (app.GaussSeidelButton.Value)
                    [results] = gauss_seidel(app, matrix, app.totalNumberOfEquations, xs);
                end
            end
            
            time = toc;
            if (app.errorFlag == 0) 
                
                out = fopen("results.txt", "w");
                
                % Gauss-Seidel requires unique output format
                if (~app.GaussSeidelButton.Value)
                    
                    % print results in pretty tabular format
                    printHeader(app, out, cell2mat(app.variableMap.keys()));
                    printLine(app, out, results);
                    
                else
                    % print results in pretty tabular format
                    printHeader(app, out, results(1,:)); 
                    
                    % the amount of results is the length of 1 column.
                    % because first row contains headings only, start on row 2
                    num_iters = length(results(:,1));
                    for i=2:(num_iters)
                        % print each variable
                        printLine(app, out, results(i,:));
                    end
                end
                app.ExecutionTimeEditField.Value = time;
                fprintf(out, "\n******************\nPLEASE CLOSE NOTEPAD TO PROCEED WITH APPLICATION\n****************");
                fclose(out);
                !notepad results.txt
                
            end        
            
        end

        % Callback function
        function DeleterowcolButtonPushed(app, event)
            for i=1:app.numRowsCols
                %                 app.EquationEditFields(i, app.numRowsCols) = [];
            end
            app.EquationEditFields(1,:) = [];
            app.numRowsCols = app.numRowsCols - 1;
        end

        % Callback function
        function AnotherinstanceButtonPushed(app, event)
            linear_solver();
        end

        % Button pushed function: LoadFromFileButton
        function LoadFromFileButtonPushed(app, event)
            % promts user to select file containing function
            [file, pathname] = uigetfile("*.txt");
            
            % construct full file path
            filePath = fullfile(pathname, file);
            
            % store text of the equations here
            text = "";
            
            if file ~= 0
                
                % open file
                fid = fopen(filePath);
                
                % get the number of equations we have
                % and put that into the textbox
                no_of_eqs = str2double(fgetl(fid));
                app.NumberofEquationsEditField.Value = no_of_eqs;
                app.totalNumberOfEquations = no_of_eqs;
                
                % get the type of the algorithm
                % make it lower case for case-insensitive comparison
                type = lower(convertCharsToStrings(fgetl(fid))); 
                
                % set the radio button accoriding to type of algorithm from text file
                if (type == "gaussian-elimination" || type == "gaussian elimination")
                    app.GaussianeliminationButton.Value = 1;
                elseif (type == "lu decomposition")
                    app.LUdecompositionButton.Value = 1;
                elseif (type == "gaussian-jordan" || type == "gaussian jordan") 
                    app.GaussianJordanButton.Value = 1;
                elseif (type == "gauss-seidel" || type == "gauss seidel")
                    app.GaussSeidelButton.Value = 1;   
                end
                
                %initialize with first line
                text = convertCharsToStrings(fgetl(fid));
                
                % To keep track of current line. Start on 2nd line since 
                % first has already been read
                currentLine = 2;
                
                % get the equations in text file and put them into text box,
                % do this by concatanating every line with a newline character
                % in between each concatanation. stop when you reached
                % end of file or read the specified number of equations
                while (currentLine <= no_of_eqs && ~feof(fid))
                    
                    % get next line
                    nextLine = convertCharsToStrings(fgetl(fid));
                    
                    % Add a newline character if it's not last line
                    text = text + newline + nextLine;
                    
                    
                    currentLine = currentLine + 1;
                end
                
                % display all that text into the big text box
                app.FunctionsTextArea.Value = text; 
                
                % checking for some problems
                if (currentLine <= no_of_eqs)
                   app.ErrorsLabel.Text = "Error" + newline + "Number of equations provided is incosistant" + newline + "with number of equations specified" ;
                end
                
                % In case of gauss-seidel, last line should contain initial points
                % handle case where there are no init points
                if (type == "gauss-seidel" || type == "gauss seidel")
                   if (feof(fid))
                       app.ErrorsLabel.Text = "Error:" + newline + "There were no initial points  or format is inconcistant.";
                   else
                       nextLine = convertCharsToStrings(fgetl(fid));
                       app.InitialPointsforSeideEditField.Value = nextLine;
                   end
                end
                
                
                % UI figure has this weird behaviour where it sends App Designer
                % and its apps to the background. This is a workaround that forces
                % current app to the fron
                figure(app.UIFigure);
                
                fclose(fid);
            end
        end

        % Button pushed function: UseAllMethodsButton
        function UseAllMethodsButtonPushed(app, event)
            % reset the error flag every time you attempt to solve
            app.errorFlag = 0;
            app.ErrorsLabel.Text = "";
            
            app.totalNumberOfEquations = double(app.NumberofEquationsEditField.Value);
            
            tic;
            [~] = checkFunctions(app);
            
            if (app.errorFlag == 0)
                [matrix, xs] = fill_matrix(app);
            end

            if (app.errorFlag == 0)
                output_GE = gaussian_elimination(app, matrix,app.totalNumberOfEquations);
                results_GE  = getOutputGaussianelimination(app, output_GE, app.totalNumberOfEquations);
            end
            
            if (app.errorFlag == 0)
                [output_L, ~] = lu_decomposition(app, matrix, app.totalNumberOfEquations);
                results_LU  = getOutputLUdecomposition(app, output_L, app.totalNumberOfEquations);
            end
            
            if (app.errorFlag == 0)
                output_GJ = gaussian_jordan(app, matrix, app.totalNumberOfEquations);
                results_GJ  = getOutputGaussianJordan(app, output_GJ, app.totalNumberOfEquations);
            end
            
            if (app.errorFlag == 0)
                results_GS = gauss_seidel(app, matrix, app.totalNumberOfEquations, xs);
            end
            time = toc;
            app.ExecutionTimeEditField.Value = double(time);
            
            if (app.errorFlag == 0)
                
                out = fopen("results.txt", "w");
                
                if (out)
                    heading = cell2mat(app.variableMap.keys());
                    
                    % print results in pretty tabular format
                    fprintf(out, "\n----------------------------------------------------\n");
                    fprintf(out, "Gausian Elimination:\n\n");
                    printHeader(app, out, heading);
                    printLine(app, out, results_GE);
                    
                    % print results in pretty tabular format
                    fprintf(out, "\n----------------------------------------------------\n");
                    fprintf(out, "Gausian Jordan:\n\n");
                    printHeader(app, out, heading);
                    printLine(app, out, results_GJ);
                    
                    % print results in pretty tabular format
                    fprintf(out, "\n----------------------------------------------------\n");
                    fprintf(out, "LU decomposition:\n\n");
                    printHeader(app, out, heading);
                    printLine(app, out, results_LU);
                    
                    % print results in pretty tabular format
                    fprintf(out, "\n----------------------------------------------------\n");
                    fprintf(out, "Gauss_seidel:\n\n");
                    printHeader(app, out, results_GS(1,:)); 
                    
                    % the amount of results is the length of 1 column.
                    % because first row contains headings only, start on row 2
                    num_iters = length(results_GS(:,1));
                    for i=2:(num_iters)
                        % print each variable
                        printLine(app, out, results_GS(i,:));
                    end
    
                    fprintf(out, "\n******************\nPLEASE CLOSE NOTEPAD TO PROCEED WITH APPLICATION\n****************");
                    fclose(out);
                    !notepad results.txt
                end
                
            end

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.FunctionsTextAreaLabel = uilabel(app.UIFigure);
            app.FunctionsTextAreaLabel.Text = 'Functions';
            app.FunctionsTextArea = uitextarea(app.UIFigure);
            app.LoadFromFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButtonPushed, true);
            app.LoadFromFileButton.Text = 'Load From File';
            app.NumberofEquationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofEquationsEditFieldLabel.Text = 'Number of Equations';
            app.NumberofEquationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialPointsforSeidelSpaceseparatedLabel = uilabel(app.UIFigure);
            app.InitialPointsforSeidelSpaceseparatedLabel.Text = 'Initial Points for Seidel (Space separated)';
            app.InitialPointsforSeideEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorsLabel = uilabel(app.UIFigure);
            app.ErrorsLabel.Text = '';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.GaussianeliminationButton = uiradiobutton(app.MethodButtonGroup);
            app.GaussianeliminationButton.Text = 'Gaussian-elimination';
            app.GaussianeliminationButton.Value = true;
            app.GaussianJordanButton = uiradiobutton(app.MethodButtonGroup);
            app.GaussianJordanButton.Text = 'GaussianJordan';
            app.GaussSeidelButton = uiradiobutton(app.MethodButtonGroup);
            app.GaussSeidelButton.Text = ' Gauss-Seidel';
            app.LUdecompositionButton = uiradiobutton(app.MethodButtonGroup);
            app.LUdecompositionButton.Text = 'LU decomposition';
            app.InputforSeidelPanel = uipanel(app.UIFigure);
            app.InputforSeidelPanel.Title = 'Input for Seidel';
            app.PrecisionEditField_3Label = uilabel(app.InputforSeidelPanel);
            app.PrecisionEditField_3Label.Text = 'Precision';
            app.PrecisionInputSeidelEditField = uieditfield(app.InputforSeidelPanel, 'numeric');
            app.PrecisionInputSeidelEditField.Value = 1e-05;
            app.MaxIterationsEditFieldLabel = uilabel(app.InputforSeidelPanel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.InputforSeidelPanel, 'numeric');
            app.MaxIterationsEditField.Value = 50;
            app.ResultsofSeidelPanel = uipanel(app.UIFigure);
            app.ResultsofSeidelPanel.Title = 'Results of Seidel';
            app.IterationsEditFieldLabel = uilabel(app.ResultsofSeidelPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsResultEditField = uieditfield(app.ResultsofSeidelPanel, 'numeric');
            app.IterationsResultEditField.Editable = 'off';
            app.PrecisionErrorLabel = uilabel(app.ResultsofSeidelPanel);
            app.PrecisionErrorLabel.Text = 'Precision Error';
            app.PrecisionResultEditField = uieditfield(app.ResultsofSeidelPanel, 'numeric');
            app.PrecisionResultEditField.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.UseAllMethodsButton = uibutton(app.UIFigure, 'push');
            app.UseAllMethodsButton.ButtonPushedFcn = createCallbackFcn(app, @UseAllMethodsButtonPushed, true);
            app.UseAllMethodsButton.Text = 'Use All Methods';
            app.ExecutionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExecutionTimeEditFieldLabel.Text = 'Execution Time';
            app.ExecutionTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExecutionTimeEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = linear_solver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end