classdef plotFunction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        RangeLabel         matlab.ui.control.Label
        StartEditField     matlab.ui.control.NumericEditField
        EndEditField       matlab.ui.control.NumericEditField
        toLabel            matlab.ui.control.Label
        PlotButton         matlab.ui.control.Button
        FunctionLabel      matlab.ui.control.Label
        FunctionEditField  matlab.ui.control.EditField
    end


    properties (Access = private)
        functionToPlot % Description
        callingApp
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, func)
            app.functionToPlot = func;
            app.FunctionEditField.Value = func;
            app.callingApp = mainApp;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            syms func(x)
            func(x) = str2sym(app.functionToPlot);
            intervalStart = app.StartEditField.Value;
            intervalEnd = app.EndEditField.Value;
            
            fplot(app.UIAxes, func(x), [intervalStart, intervalEnd]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range:';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEditField = uieditfield(app.UIFigure, 'numeric');
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function:';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = plotFunction(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end