classdef analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyzerUIFigure             matlab.ui.Figure
        MethodButtonGroup            matlab.ui.container.ButtonGroup
        BisectionButton              matlab.ui.control.RadioButton
        FalsepositionButton          matlab.ui.control.RadioButton
        FixedpointButton             matlab.ui.control.RadioButton
        NewtonRaphsonButton          matlab.ui.control.RadioButton
        SecantButton                 matlab.ui.control.RadioButton
        XlEditFieldLabel             matlab.ui.control.Label
        XlEditField_Bisection        matlab.ui.control.NumericEditField
        XlEditField_2Label           matlab.ui.control.Label
        XlEditField_False            matlab.ui.control.NumericEditField
        XuEditField_2Label           matlab.ui.control.Label
        XuEditField_False            matlab.ui.control.NumericEditField
        X0EditFieldLabel             matlab.ui.control.Label
        X0EditField_Fixed            matlab.ui.control.NumericEditField
        X0EditField_2Label           matlab.ui.control.Label
        X0EditField_Newton           matlab.ui.control.NumericEditField
        X0EditField_3Label           matlab.ui.control.Label
        X0EditField_Secant           matlab.ui.control.NumericEditField
        InputFunctionEditFieldLabel  matlab.ui.control.Label
        InputFunctionEditField       matlab.ui.control.EditField
        XuEditField_3Label           matlab.ui.control.Label
        XuEditField_Bisection        matlab.ui.control.NumericEditField
        SinButton                    matlab.ui.control.Button
        CosButton                    matlab.ui.control.Button
        TanButton                    matlab.ui.control.Button
        expButton                    matlab.ui.control.Button
        sqrtButton                   matlab.ui.control.Button
        SolveButton                  matlab.ui.control.Button
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel      matlab.ui.control.Label
        PrecisionEditField           matlab.ui.control.NumericEditField
        iEditFieldLabel              matlab.ui.control.Label
        iEditField                   matlab.ui.control.NumericEditField
        LoadfromFileButton           matlab.ui.control.Button
        ErrorLabel                   matlab.ui.control.Label
        gxEditFieldLabel             matlab.ui.control.Label
        gxEditField                  matlab.ui.control.EditField
        X1Label                      matlab.ui.control.Label
        X1EditField_Secant           matlab.ui.control.NumericEditField
        PlotFunctionButton           matlab.ui.control.Button
        TimeelsapsedEditFieldLabel   matlab.ui.control.Label
        TimeelsapsedEditField        matlab.ui.control.NumericEditField
        TrySingleStepwithBisectionButton  matlab.ui.control.Button
        nthrootButton                matlab.ui.control.Button
        EaEditFieldLabel             matlab.ui.control.Label
        EaEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        
        MaxIter = 0;     % Maximum number of iterations before stopping
        Precision = 0;  % desired precision at which execution stops
        func;
        funcDiff;
        plottingApp    % app that plots a function
        singleStepApp   % app that displays in single step
        results
    end
    
    methods (Access = public)
        
        function ret = recordEntries(app, i, xr, ea)
            app.results(i, 1) = i;
            app.results(i, 2) = xr;
            app.results(i, 4) = ea;
            ret = 0;
        end
        
        % Bisection
        function ret = Bisection(app)
            
            xl = app.XlEditField_Bisection.Value;
            xu = app.XuEditField_Bisection.Value;
            xr_old = 0;
            ea = 100;
            if eval(app.func(xl)) * eval(app.func(xu)) > 0
                disp("No Solution!");
                xr = 0;
                i = 0;
                app.ErrorLabel.Text = "No solution in range";
            else
                for i=1:1:app.MaxIter
                    
                    % compute midpoin xr
                    xr = (xu + xl)/2;
                    
                    % compute test = f(xl) * f(xr)
                    test = eval(app.func(xl)) * eval(app.func(xr));
                    
                    if test < 0
                        xr_old = xu;
                        xu = xr;
                    elseif test > 0
                        xr_old = xl;
                        xl = xr;
                    end
                    
                    if test == 0
                        ea = 0;
                    else
                        % approximate relative error
                        ea = abs((xr - xr_old)/xr);
                    end
                    
                    % add results to table
                    recordEntries(app, i, xr, ea);
                    
                    if ea < app.Precision
                        break;
                    end
                end
            end
            ret = [xr, i, ea];
        end
        
        % FalsePosition
        function ret = FalsePosition(app)
            
            xl = app.XlEditField_False.Value;
            xu = app.XuEditField_False.Value;
            xr_old=0;
            ea = 100;
            % on solution if both starting points have same sign
            if eval(app.func(xl))*eval(app.func(xu)) > 0
                disp("No Solution!");
                xr = 0;
                i = 0;
                app.ErrorLabel.Text = "No solution in range";
            else
                for i=1:app.MaxIter
                    
                    % compute midpoin xr
                    % minimize functon call
                    fu = eval(app.func(xu));
                    fl = eval(app.func(xl));
                    xr = (xl * fu - xu * fl)/(fu - fl);
                    
                    % compute test = f(xl) * f(xr)
                    test = fl * eval(app.func(xr));
                    
                    if test < 0
                        xr_old = xu;
                        xu = xr;
                    elseif test > 0
                        xr_old = xl;
                        xl = xr;
                    end
                    
                    if test == 0
                        ea = 0;
                    else
                        % approximate relative error
                        ea = abs((xr - xr_old)/xr);
                    end
                    
                    % add results to table
                    recordEntries(app, i, xr, ea);
                    
                    if ea < app.Precision
                        break;
                    end
                end
            end
            
            ret = [xr, i, ea];
        end
        
        % Fixedpoint
        function ret = Fixedpoint(app)
            x0 = app.X0EditField_Fixed.Value;
            
            syms gFunc(x)
            gFunc(x) = str2sym(app.gxEditField.Value);

            
            xr = x0;
            
            for i=1:1:app.MaxIter
                
                xr_old = xr;
                
                xr = eval(gFunc(xr_old));
                
                ea = abs((xr - xr_old)/xr);
                
                  % add results to table
                recordEntries(app, i, xr, ea);
                
                if (ea < app.Precision)
                    break
                end
            end
            
            ret = [xr, i, ea];
        end
        
        % NewtonRaphson
        function ret = NewtonRaphson(app)
            
            xr_old = app.X0EditField_Newton.Value;
            
            ea = 100;
            
            for i=1:1:app.MaxIter
                
                xr = xr_old - ((eval(app.func(xr_old)))/(eval(app.funcDiff(xr_old))));
                
                ea = abs((xr - xr_old)/xr);
                
                % add results to table
                recordEntries(app, i, xr, ea);
                
                if (ea < app.Precision)
                    break
                end
                
                xr_old = xr;
                
            end
            ea
            ret = [xr, i, ea];
        end
        
        % Secant
        function ret = Secant(app)
            
            xi_old = app.X0EditField_Secant.Value;
            xi = app.X1EditField_Secant.Value;
            
            if xi == xi_old
                app.ErrorLabel.Text = "Initial values must be different!";
                ret = [0, 0, 0];
                return
            end
            
            for i=1:1:app.MaxIter
                
                xiplus = eval(xi - (app.func(xi)*(xi_old - xi))/(app.func(xi_old)-app.func(xi)));
                
                ea = abs((xiplus-xi)/xiplus);
                
                % add results to table
                recordEntries(app, i, xiplus, ea);
                
                if (ea < app.Precision)
                    break
                end
                
                xi_old = xi;
                xi = xiplus;
                
            end
            
            ret = [xiplus, i, ea];
        end
        
        function ret = returnFromSinglStep(app, x, i)
            app.BisectionButton.Enable = 'on';
            app.FalsepositionButton.Enable = 'on';
            app.FixedpointButton.Enable = 'on';
            app.NewtonRaphsonButton.Enable = 'on';
            app.SecantButton.Enable = 'on';
            
            app.XEditField.Value = x;
            app.iEditField.Value = i;
            
            ret = 0;
        end
        
    end

    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            input = app.InputFunctionEditField.Value;
            
            if input == ""
                app.ErrorLabel.Text = "Please add a function!";
                return
            end
            
            syms inputFunc(x)
            inputFunc(x) = str2sym(app.InputFunctionEditField.Value);
            inputFuncDiff = diff(inputFunc);
            
            app.func = inputFunc;
            app.funcDiff = inputFuncDiff;
            
            app.MaxIter = app.MaxIterationsEditField.Value;
            app.Precision = app.PrecisionEditField.Value;
            
            app.ErrorLabel.Text = "";
            
            % Initialize array to store data
            app.results = zeros(app.MaxIter, 4);
            
            
            tic;
            if app.BisectionButton.Value
                
                % invoke bisection function
                res = Bisection(app);
                
            elseif app.FalsepositionButton.Value
                
                % invoke False Position function
                res = FalsePosition(app);
                
            elseif app.FixedpointButton.Value
                
                % invoke Fixed point function
                res = Fixedpoint(app);
                
            elseif app.NewtonRaphsonButton.Value
                
                % invoke Newton Raphson function
                res = NewtonRaphson(app);
                
            elseif app.SecantButton.Value
                
                % invoke Secant function
                res = Secant(app);
                
            else
                res = 0;
            end
            
            timeElapsed = toc;
            
            % apply function to result of each iteration
            app.results(:,3) = eval(app.func(app.results(:,2)));
    
            % display table only if progress has been made i.e. iterations not 0
            if double(res(2)) ~= 0
                f = figure(1);
                uit = uitable(f);
                uit.ColumnName = {'Iteration', 'xr', 'f(xr)', 'Precision Error'};
                uit.Data = app.results;
                uit.Position = [0 20 350 400];
            end
            
            app.TimeelsapsedEditField.Value = timeElapsed;
            app.XEditField.Value = double(res(1));
            app.iEditField.Value = double(res(2));
            app.EaEditField.Value = double(res(3));
            
        end

        % Button pushed function: SinButton
        function SinButtonPushed(app, event)
            text = app.InputFunctionEditField.Value;
            text = strcat(text, " sin(");
            app.InputFunctionEditField.Value = text;
        end

        % Button pushed function: CosButton
        function CosButtonPushed(app, event)
            text = app.InputFunctionEditField.Value;
            text = strcat(text, " cos(");
            app.InputFunctionEditField.Value = text;
        end

        % Button pushed function: TanButton
        function TanButtonPushed(app, event)
            text = app.InputFunctionEditField.Value;
            text = strcat(text, " tan(");
            app.InputFunctionEditField.Value = text;
        end

        % Button pushed function: expButton
        function expButtonPushed(app, event)
            text = app.InputFunctionEditField.Value;
            text = strcat(text, " exp(");
            app.InputFunctionEditField.Value = text;
        end

        % Button pushed function: sqrtButton
        function sqrtButtonPushed(app, event)
            text = app.InputFunctionEditField.Value;
            text = strcat(text, " sqrt(");
            app.InputFunctionEditField.Value = text;
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            
            % promts user to select file containing function
            [file, pathname] = uigetfile("*.txt");
            
            % construct full file path
            filePath = fullfile(pathname, file);
            
            if file ~= 0
                % open file
                fileToScan = fopen(filePath);
                
                % read contens of file
                data = fgetl(fileToScan);
                
                % close file
                fclose(fileToScan);
                
                % put contents of file in input edit field
                app.InputFunctionEditField.Value = data;
            end
            
            
        end

        % Button pushed function: TrySingleStepwithBisectionButton
        function SingleStepModeButtonPushed(app, event)
%             app.BisectionButton.Enable = 'off';
%             app.FalsepositionButton.Enable = 'off';
%             app.FixedpointButton.Enable = 'off';
%             app.NewtonRaphsonButton.Enable = 'off';
%             app.SecantButton.Enable = 'off';
            app.singleStepApp = single_step_mode(app);
        end

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
            app.plottingApp = plotFunction(app, app.InputFunctionEditField.Value);
        end

        % Button pushed function: nthrootButton
        function nthrootButtonPushed(app, event)
            text = app.InputFunctionEditField.Value;
            text = strcat(text, " nthroot( , 3) ");
            app.InputFunctionEditField.Value = text;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyzerUIFigure = uifigure;
            app.AnalyzerUIFigure.Name = 'Analyzer';
            app.MethodButtonGroup = uibuttongroup(app.AnalyzerUIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.BisectionButton = uiradiobutton(app.MethodButtonGroup);
            app.BisectionButton.Text = 'Bisection';
            app.BisectionButton.Value = true;
            app.FalsepositionButton = uiradiobutton(app.MethodButtonGroup);
            app.FalsepositionButton.Text = 'False-position';
            app.FixedpointButton = uiradiobutton(app.MethodButtonGroup);
            app.FixedpointButton.Text = 'Fixed point';
            app.NewtonRaphsonButton = uiradiobutton(app.MethodButtonGroup);
            app.NewtonRaphsonButton.Text = 'Newton-Raphson';
            app.SecantButton = uiradiobutton(app.MethodButtonGroup);
            app.SecantButton.Text = 'Secant';
            app.XlEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.XlEditFieldLabel.Text = 'Xl';
            app.XlEditField_Bisection = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.XlEditField_2Label = uilabel(app.AnalyzerUIFigure);
            app.XlEditField_2Label.Text = 'Xl';
            app.XlEditField_False = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.XuEditField_2Label = uilabel(app.AnalyzerUIFigure);
            app.XuEditField_2Label.Text = 'Xu';
            app.XuEditField_False = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.X0EditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField_Fixed = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.X0EditField_2Label = uilabel(app.AnalyzerUIFigure);
            app.X0EditField_2Label.Text = 'X0';
            app.X0EditField_Newton = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.X0EditField_3Label = uilabel(app.AnalyzerUIFigure);
            app.X0EditField_3Label.Text = 'X0';
            app.X0EditField_Secant = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.InputFunctionEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.InputFunctionEditFieldLabel.Text = 'Input Function ';
            app.InputFunctionEditField = uieditfield(app.AnalyzerUIFigure, 'text');
            app.XuEditField_3Label = uilabel(app.AnalyzerUIFigure);
            app.XuEditField_3Label.Text = 'Xu';
            app.XuEditField_Bisection = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.SinButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.SinButton.ButtonPushedFcn = createCallbackFcn(app, @SinButtonPushed, true);
            app.SinButton.Text = 'Sin';
            app.CosButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.CosButton.ButtonPushedFcn = createCallbackFcn(app, @CosButtonPushed, true);
            app.CosButton.Text = 'Cos';
            app.TanButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.TanButton.ButtonPushedFcn = createCallbackFcn(app, @TanButtonPushed, true);
            app.TanButton.Text = 'Tan';
            app.expButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.expButton.ButtonPushedFcn = createCallbackFcn(app, @expButtonPushed, true);
            app.expButton.Text = 'exp';
            app.sqrtButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.sqrtButton.ButtonPushedFcn = createCallbackFcn(app, @sqrtButtonPushed, true);
            app.sqrtButton.Text = 'sqrt';
            app.SolveButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve!';
            app.XEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.12f';
            app.XEditField.Editable = 'off';
            app.MaxIterationsEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.MaxIterationsEditField.Value = 50;
            app.PrecisionEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.PrecisionEditField.ValueDisplayFormat = '%e';
            app.PrecisionEditField.Value = 1e-05;
            app.iEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.iEditField.Editable = 'off';
            app.LoadfromFileButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = 'Load from File';
            app.ErrorLabel = uilabel(app.AnalyzerUIFigure);
            app.ErrorLabel.Text = '';
            app.gxEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.gxEditFieldLabel.Text = 'g(x)';
            app.gxEditField = uieditfield(app.AnalyzerUIFigure, 'text');
            app.X1Label = uilabel(app.AnalyzerUIFigure);
            app.X1Label.Text = 'X1';
            app.X1EditField_Secant = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.PlotFunctionButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            app.TimeelsapsedEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.TimeelsapsedEditFieldLabel.Text = 'Time elsapsed';
            app.TimeelsapsedEditField = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.TimeelsapsedEditField.Editable = 'off';
            app.TrySingleStepwithBisectionButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.TrySingleStepwithBisectionButton.ButtonPushedFcn = createCallbackFcn(app, @SingleStepModeButtonPushed, true);
            app.TrySingleStepwithBisectionButton.Text = 'Try Single Step with Bisection';
            app.nthrootButton = uibutton(app.AnalyzerUIFigure, 'push');
            app.nthrootButton.ButtonPushedFcn = createCallbackFcn(app, @nthrootButtonPushed, true);
            app.nthrootButton.Text = 'nthroot';
            app.EaEditFieldLabel = uilabel(app.AnalyzerUIFigure);
            app.EaEditFieldLabel.Text = 'Ea';
            app.EaEditField = uieditfield(app.AnalyzerUIFigure, 'numeric');
            app.EaEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = analyze

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyzerUIFigure)
        end
    end
end