classdef single_step_mode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        NextIterationButton  matlab.ui.control.Button
        FunctionLabel        matlab.ui.control.Label
        FunctionEditField    matlab.ui.control.EditField
        RangeLabel           matlab.ui.control.Label
        StartEditField       matlab.ui.control.NumericEditField
        toLabel              matlab.ui.control.Label
        EndEditField         matlab.ui.control.NumericEditField
        CloseButton          matlab.ui.control.Button
        IEditFieldLabel      matlab.ui.control.Label
        IEditField           matlab.ui.control.NumericEditField
        EaEditFieldLabel     matlab.ui.control.Label
        EaEditField          matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        callingApp % callin app
        height
        Precision
        
        
        % all possible properties that might be used
        func
        funcDiff
        xu
        xl
        xr
        xr_old
        xi
        xi_old
        intervalStart
        intervalEnd
        currentIter

    end

    methods (Access = public)
    
        function ret = setUpBisection(app)
            
            app.xl = app.callingApp.XlEditField_Bisection.Value;
            app.xu = app.callingApp.XuEditField_Bisection.Value;
            
            app.StartEditField.Value = app.xl;
            app.EndEditField.Value = app.xu;
            
            app.currentIter = 0;
            
            app.intervalStart = app.xl;
            app.intervalEnd = app.xu;
            
            app.height = max(app.func(app.xl), app.func(app.xu));
            
            
            ret = 0;
        end     
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
            app.FunctionEditField.Value = app.callingApp.InputFunctionEditField.Value;
            
            syms inputFunc(x)
            inputFunc(x) = str2sym(app.FunctionEditField.Value);
            app.func = inputFunc;
            
            % invoke Bisection function
            setUpBisection(app);
            
            app.Precision = app.callingApp.Precision;

        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
           returnFromSinglStep(app.callingApp, 1, 1);
           delete(app);
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            app.intervalStart = value;
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            app.intervalEnd = value;
        end

        % Button pushed function: NextIterationButton
        function NextIterationButtonPushed(app, event)

            % compute midpoin xr
            app.xr = (app.xu + app.xl)/2;
            
            % compute test = f(xl) * f(xr)
            test = app.func(app.xl) * app.func(app.xr);
            
            if test < 0
                app.xr_old = app.xu;
                app.xu = app.xr;
            elseif test > 0
                app.xr_old = app.xl;
                app.xl = app.xr;
            end
            
            if test == 0
                ea = 0;
            else
                % approximate relative error
                ea = abs((app.xr - app.xr_old)/app.xr) * 100;
            end                
            
            app.currentIter = app.currentIter + 1;
            
            app.XEditField.Value = app.xr;
            app.IEditField.Value = app.currentIter;
            app.EaEditField.Value = double(ea);
            
            
            x = linspace(app.intervalStart,app.intervalEnd);
            y = app.func(x);
            plot(app.UIAxes, x, y);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [app.xl app.xl], [-app.height, app.height]);
            plot(app.UIAxes, [app.xu app.xu], [-app.height, app.height]);
            hold(app.UIAxes, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NextIterationButton = uibutton(app.UIFigure, 'push');
            app.NextIterationButton.ButtonPushedFcn = createCallbackFcn(app, @NextIterationButtonPushed, true);
            app.NextIterationButton.Text = 'Next Iteration';
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function:';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.Editable = 'off';
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range:';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.EndEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Editable = 'off';
            app.EaEditFieldLabel = uilabel(app.UIFigure);
            app.EaEditFieldLabel.Text = 'Ea';
            app.EaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EaEditField.ValueDisplayFormat = '%.9f';
            app.EaEditField.Editable = 'off';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.9f';
            app.XEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = single_step_mode(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end